classdef autoTrimApp_v6C < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GliderTrimmerUIFigure          matlab.ui.Figure
        NOAAAOMLGliderTrimmerv6CLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        RESULTSTab                     matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        UpdateButton                   matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DiveNListBoxLabel              matlab.ui.control.Label
        GliderDropDown                 matlab.ui.control.DropDown
        DiveNListBox                   matlab.ui.control.ListBox
        GliderDropDownLabel            matlab.ui.control.Label
        AverageSwitch                  matlab.ui.control.Switch
        AverageSwitchLabel             matlab.ui.control.Label
        NoofdivesSpinner               matlab.ui.control.Spinner
        NoofdivesSpinnerLabel          matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        Label                          matlab.ui.control.Label
        INDIVIDUALRESULTSLabel         matlab.ui.control.Label
        FINALAVERAGERESULTSLabel       matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        ResultsTextArea                matlab.ui.control.TextArea
        StartButton                    matlab.ui.control.Button
        SFTPTab                        matlab.ui.container.Tab
        RemembermeButton               matlab.ui.control.Button
        OpenKmz                        matlab.ui.control.Button
        LatestdownloadsTextArea        matlab.ui.control.TextArea
        LatestdownloadsLabel           matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        ServerTextArea                 matlab.ui.control.TextArea
        ServerTextAreaLabel            matlab.ui.control.Label
        DownloadButton                 matlab.ui.control.Button
        remoteSelectButton             matlab.ui.control.Button
        remoteFolderDropDown           matlab.ui.control.DropDown
        PassEditField                  matlab.ui.control.EditField
        PassEditFieldLabel             matlab.ui.control.Label
        HostEditField                  matlab.ui.control.EditField
        HostEditFieldLabel             matlab.ui.control.Label
        DisconnectButton               matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        RemoteNCDFListBox              matlab.ui.control.ListBox
        RemoteNCDFListBoxLabel         matlab.ui.control.Label
        UserEditField                  matlab.ui.control.EditField
        UserEditFieldLabel             matlab.ui.control.Label
        CMDFILETab                     matlab.ui.container.Tab
        CopyButton                     matlab.ui.control.Button
        LastmodifiedLabel              matlab.ui.control.Label
        CheckLabel                     matlab.ui.control.Label
        CreateButton                   matlab.ui.control.Button
        impliedcmdfileLabel            matlab.ui.control.Label
        remotecmdfileLabel             matlab.ui.control.Label
        impliedsgGGGEditField          matlab.ui.control.EditField
        ImpliedcmdfileTextArea         matlab.ui.control.TextArea
        ImpliedcmdfileLabel            matlab.ui.control.Label
        ZoomSlider                     matlab.ui.control.Slider
        ZoomSliderLabel                matlab.ui.control.Label
        remotesgGGGEditField           matlab.ui.control.EditField
        ExportButton                   matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        RemotecmdfileTextArea          matlab.ui.control.TextArea
        RemotecmdfileLabel             matlab.ui.control.Label
        CONFIGTab                      matlab.ui.container.Tab
        ViewmanualButton               matlab.ui.control.Button
        DefaultconfigurationButton     matlab.ui.control.StateButton
        ActiveCheckBox                 matlab.ui.control.CheckBox
        ApplyfilterButton              matlab.ui.control.Button
        FiltersgIDTextArea             matlab.ui.control.TextArea
        FiltersgIDLabel                matlab.ui.control.Label
        SaveconfigurationButton        matlab.ui.control.Button
        DepthDropDown                  matlab.ui.control.DropDown
        DepthDropDownLabel             matlab.ui.control.Label
        UploadcmdfilenameEditField     matlab.ui.control.EditField
        UploadcmdfilenameLabel         matlab.ui.control.Label
        DAYSSpinner                    matlab.ui.control.Spinner
        DAYSSpinnerLabel               matlab.ui.control.Label
        C_VBDButtonGroup               matlab.ui.container.ButtonGroup
        cvbd50                         matlab.ui.control.RadioButton
        cvbd100                        matlab.ui.control.RadioButton
        C_ROLLTYPEButtonGroup          matlab.ui.container.ButtonGroup
        rollave                        matlab.ui.control.RadioButton
        rollrate                       matlab.ui.control.RadioButton
        roll                           matlab.ui.control.RadioButton
        C_ROLLButtonGroup              matlab.ui.container.ButtonGroup
        croll50                        matlab.ui.control.RadioButton
        croll100                       matlab.ui.control.RadioButton
        C_PITCHPGAINButtonGroup        matlab.ui.container.ButtonGroup
        pitch50                        matlab.ui.control.RadioButton
        pitch100                       matlab.ui.control.RadioButton
        NDIVESSpinner                  matlab.ui.control.Spinner
        NDIVESSpinnerLabel             matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        WorkingdirectoryEditField      matlab.ui.control.EditField
        LocaldirectorydirectorytocontainsgGGGfoldersEditFieldLabel  matlab.ui.control.Label
        SMCCSpinner                    matlab.ui.control.Spinner
        SMCCSpinnerLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        sgid % Description
        lastDive % Description
        diveSelected
        totalDives % Description
        last_file
        createcmd
        server % server object
        local_download_dir % local dir selected to download nc files and kmz
        incoming_cmdfile % cmdfile text read from basestation
        object_results % Description
        sg_full_path % Description
        cmdfile_meta % Description
        new_text_clean % Description
        full_remote_sgIDs % Description
    end
    

    %% PUBLIC ---------------------------------------------------------------------------------------------------
    methods (Access = public)
    %% --

        % create text for display in the implied cmdfile text box
        function cmdfile_comments = createText(app, cmdfile_path )
            
            fin = fopen(cmdfile_path,'r');
            new_text = ""; % cmdfile with comments
            app.new_text_clean = ""; % cmdfile without comments

            while ~feof(fin)
                line = fgetl(fin); % read line by line
                              
                if length(line) < 2 % check condition here, GO QUIT RESUME don't have "," comma
                    break; 
                end % if not a char end loop

                if contains(line,"$GO") || contains(line,"$QUIT") || contains(line,"$RESUME")
                    new_text = new_text + line; % for the end with GO QUIT RESUME
                    app.new_text_clean = app.new_text_clean + line;
                else                
%                     app.ImpliedcmdfileTextArea.Value = line; % Intermediate display of cmdfile
%                     pause(0.5); % Intermediate display of cmdfile
                    line_split = split(line,","); % split string by the comma
                    line_split = convertCharsToStrings(line_split);
                    
                    parameter_name = line_split(1);
                    parameter_value = str2double(line_split(2)); % not being used. Changes are based on last used parameter, not current cmdfile
                    change_on = false;
                    comment = "";
                    commentb = "";
    
                    switch parameter_name
                        case "$N_DIVES"
                            new_value = app.diveSelected + app.NDIVESSpinner.Value;
                            parameter_value = app.diveSelected;
                            change_on = true;
                        case "$STOP_T"
                            today = datetime('now','TimeZone','UTC','Format','MMddyy'); % date in MMDDYY format UTC
                            next = today + days(app.DAYSSpinner.Value);
                            new_value = strcat(string(next),'12'); % add MMDDYY12
                        case "$SM_CC"                            
                            new_value = (app.object_results.imp_sm_cc_ave - app.object_results.last_sm_cc) + app.object_results.last_sm_cc;
                            parameter_value = app.object_results.last_sm_cc;                            
                            change_on = true;
%                         case "$D_TGT"
%                             
%                             new_value = 
%                             parameter_value = 
%                             change_on = 
                        case "$C_VBD"
                            if app.cvbd50.Value == true; cvbd_change = 0.5; comment = " 50% "; else; cvbd_change = 1.0; comment = " 100% "; end
                            new_value = round((app.object_results.imp_cvbd_ave - app.object_results.last_c_vbd)*cvbd_change + app.object_results.last_c_vbd,0);
                            parameter_value = app.object_results.last_c_vbd;
                            change_on = true;
                        case "$C_PITCH"
                            if app.pitch50.Value == true; pitch_change = 0.5; comment = " x50% "; else; pitch_change = 1.0; comment = " 100% "; end
                            new_value = round((app.object_results.imp_c_pitch_ave - app.object_results.last_c_pitch)*pitch_change + app.object_results.last_c_pitch,0);
                            parameter_value = app.object_results.last_c_pitch;
                            change_on = true;
                        case "$PITCH_GAIN"
                            if app.pitch50.Value == true; pitch_change = 0.5; comment = " 50% "; else; pitch_change = 1.0; comment = " 100% "; end
                            new_value = round((app.object_results.imp_pitch_gain_ave - app.object_results.last_pitch_gain)*pitch_change + app.object_results.last_pitch_gain,1);
                            parameter_value = app.object_results.last_pitch_gain;
                            change_on = true;
                        case "$C_ROLL_DIVE"
                            if app.croll50.Value == true; roll_change = 0.5; comment = " 50% "; else; roll_change = 1.0; comment = " 100% "; end
                            if app.roll.Value == true
                                commentb = " ROLL ";
                                new_value = round((app.object_results.imp_croll_dive_ave - app.object_results.last_c_roll_dive)*roll_change + app.object_results.last_c_roll_dive,0);                                
                            elseif app.rollrate.Value == true
                                commentb = " RATE ";
                                new_value = round((app.object_results.imp_croll_turn_dive_ave - app.object_results.last_c_roll_dive)*roll_change + app.object_results.last_c_roll_dive,0);                                
                            else % average both
                                commentb = " AVERAGE ";
                                new_value = round((app.object_results.croll_dive_ave - app.object_results.last_c_roll_dive)*roll_change + app.object_results.last_c_roll_dive,0);                                
                            end
                            parameter_value = app.object_results.last_c_roll_dive;
                            change_on = true;
                        case "$C_ROLL_CLIMB"
                            if app.croll50.Value == true; roll_change = 0.5; comment = " 50% "; else; roll_change = 1.0; comment = " 100% "; end
                            if app.roll.Value == true
                                commentb = " ROLL ";
                                new_value = round((app.object_results.imp_croll_climb_ave - app.object_results.last_c_roll_climb)*roll_change + app.object_results.last_c_roll_climb,0);                                
                            elseif app.rollrate.Value == true
                                commentb = " RATE ";
                                new_value = round((app.object_results.imp_croll_turn_climb_ave - app.object_results.last_c_roll_climb)*roll_change + app.object_results.last_c_roll_climb,0);                                
                            else % average both
                                commentb = " AVERAGE ";
                                new_value = round((app.object_results.croll_climb_ave - app.object_results.last_c_roll_climb)*roll_change + app.object_results.last_c_roll_climb,0);                                
                            end
                            parameter_value = app.object_results.last_c_roll_climb;
                            change_on = true;                       
                        % Default for switch just pass cmdfile values
                        otherwise
                            new_value = parameter_value; % if not one of the calculated values use the same from cmdfile
                    end % SWITCH
                    
                    diff = 0;
                    if isnumeric(new_value) && isnumeric(parameter_value)
                        diff = new_value - parameter_value;
                    end
    
                    if change_on == true && diff ~=0                
                        change = "  >>  (" + string(diff) + ")";
                    else
                        change = "";
                    end    
                    new_text = new_text + parameter_name + "," + string(new_value) + change + comment + commentb + newline; % display with comments 
                    app.new_text_clean = app.new_text_clean + parameter_name + "," + string(new_value) + newline; % keep clean text to use as new cmdfile
                    app.ImpliedcmdfileTextArea.Value = new_text;
%                     pause(0.3);
                end % IF GO RESUME QUIT

            end % WHILE
            fclose(fin);
            cmdfile_comments = new_text; 
%             app.ImpliedcmdfileTextArea.Value = new_text;

        end

% %     object_results.last_c_vbd = last_c_vbd;
% %     object_results.imp_cvbd_ave = round(imp_cvbd_ave,0);
% %     object_results.last_c_pitch = last_c_pitch;
% %     object_results.imp_c_pitch_ave = round(imp_c_pitch_ave,0); 
% %     object_results.last_pitch_gain = last_pitch_gain;
% %     object_results.imp_pitch_gain_ave = round(imp_pitch_gain_ave,1);
% %     object_results.last_c_roll_dive = last_c_roll_dive;
% %     object_results.imp_croll_dive_ave = round(imp_croll_dive_ave,0); 
% %     object_results.imp_croll_turn_dive_ave = round(imp_croll_turn_dive_ave,0); 
% %     object_results.croll_dive_ave = round(croll_dive_ave,0);
% %     object_results.last_c_roll_climb = last_c_roll_climb;
% %     object_results.imp_croll_climb_ave = round(imp_croll_climb_ave,0); 
% %     object_results.imp_croll_turn_climb_ave = round(imp_croll_turn_climb_ave,0); 
% %     object_results.croll_climb_ave = round(croll_climb_ave,0);                             
% %     object_results.last_sm_cc = last_sm_cc;
% %     object_results.surf_min_ave = round(surf_min_ave,0); 
% %     object_results.imp_sm_cc_ave = round(imp_sm_cc_ave,0);                          
% %     object_results.last_max_buoy = last_max_buoy;
% %     object_results.dog_ave = round(dog_ave,2);

        % List gliders in current Working Directory
        
        
%         function depth_times = LoadTimedepths(app)
%             d960.dtgt = 960; d960.dabort = 990; d960.tdive = 320; d960.tmission = 380; d960.tabort = 760;
%             d900.dtgt = 900; d900.dabort = 925; d900.tdive = 300; d900.tmission = 360; d900.tabort = 720;
%             d800.dtgt = 800; d800.dabort = 825; d800.tdive = 260; d800.tmission = 310; d800.tabort = 620;
%             d700.dtgt = 700; d700.dabort = 725; d700.tdive = 230; d700.tmission = 280; d700.tabort = 560;
%             d600.dtgt = 600; d600.dabort = 625; d600.tdive = 200; d600.tmission = 240; d600.tabort = 480;            
%         end

        
        function load_credentials(app,credential_path)
            try
                if exist(credential_path,"file")
                    fcred = fopen(credential_path,"r");
                    usr = fgetl(fcred);
                    pass = fgetl(fcred);
                    fclose(fcred);
                    app.UserEditField.Value = string(usr);
                    app.PassEditField.Value = string(pass);
                end
            catch e
                uialert(app.GliderTrimmerUIFigure,e.message,'Error');
            end            
        end


        function load_configuration(app,config_path)
            
            try
                if exist(config_path,"file")
                    fconf = fopen(config_path,"r");
                    % read lines in the same order as they were saved
                    % load cvbd
                    app.cvbd100.Value = strcmpi(fgetl(fconf), "true");          
                    app.cvbd50.Value = strcmpi(fgetl(fconf), "true");           
                    % load pitch
                    app.pitch100.Value = strcmpi(fgetl(fconf), "true");              
                    app.pitch50.Value = strcmpi(fgetl(fconf), "true");                     
                    % load roll
                    app.croll100.Value = strcmpi(fgetl(fconf), "true");                
                    app.croll50.Value = strcmpi(fgetl(fconf), "true");                  
                    % load roll type
                    app.rollave.Value = strcmpi(fgetl(fconf), "true");                  
                    app.rollrate.Value = strcmpi(fgetl(fconf), "true");              
                    app.roll.Value = strcmpi(fgetl(fconf), "true");                  
                    % load +smcc
                    app.SMCCSpinner.Value = str2double( fgetl(fconf) );                 
                    % load +ndives
                    app.NDIVESSpinner.Value = str2double( fgetl(fconf) );                 
                    % load +days
                    app.DAYSSpinner.Value = str2double( fgetl(fconf) );                 
                    % load cmdfile name
                    app.UploadcmdfilenameEditField.Value = fgetl(fconf);                 
                    % load working dir
                    app.WorkingdirectoryEditField.Value = fgetl(fconf);
                    % load filter IDS                    
                    app.FiltersgIDTextArea.Value = split(fgetl(fconf),","); % get list of sgGGG from string
                    
                    fclose(fconf);
                    fclose("all");
                    % update config status button
                    app.DefaultconfigurationButton.Value = true;
                    app.DefaultconfigurationButton.BackgroundColor = "green";
                    app.DefaultconfigurationButton.Text = "Configuration loaded";
                    app.DefaultconfigurationButton.FontColor = 'white';
                    app.DefaultconfigurationButton.FontWeight = 'bold';

                end % end if file exists
            catch e
                uialert(app.GliderTrimmerUIFigure,e.message,'Error');
            end            
        end     

        
        function final_list = filterID(app,clean_list)            
            clean_len = length(clean_list);
            % create filter list
            filter_list = convertCharsToStrings(app.FiltersgIDTextArea.Value); % create string array
            % create final_list with only those sgIDs in the filter
            final_list = [""];
            i = 1;
            for x = 1:clean_len
                if ismember(clean_list(x),filter_list) % if sgGGGx is in clean_list 
                    final_list(i) = clean_list(x); % append sgGGGx to final_list
                    i = i+1; % increase final_list index
                end
            end % loop ends with final_list populated

        end


        function clean_sg_list = cleansgID(app,sg_list)
            i = 1;
            j = 1;
            clean_sg_list = [""]; % store only sgGGG folder names in this array
            while i <= length(sg_list)
                if strlength(sg_list(i)) == 5 % keep only sgGGG 5 chars
                    temp = sg_list(i);
                    temp = char(temp);
                    if ~isnan(str2double(temp(3:5)))
                        clean_sg_list(j) = sg_list(i);
                        j =j+1;
                    end
                end
                i=i+1;
            end % ends loop with clean list of sgIDs  
            
        end


        function filterids_string = list2string(app,charlist)
            filterids = convertCharsToStrings(charlist); % array of strings sgGGG
            len = length(filterids);
            filterids_string = "";
            for x = 1:len
                if x ~= len
                    filterids_string = filterids_string + filterids(x) + ",";
                else
                    filterids_string = filterids_string + filterids(x);
                end
            end            
        end


    %% --
        
%     function filterids_list = string2list(app,filterids_string)
%             % get list from comma separated values sg001,sg002
%             filterids_list = split(filterids_string,",");
%     end


    end   % END METHODS
    %% PUBLIC ---------------------------------------------------------------------------------------------------


%     methods (Access = private)
%         
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WorkingdirectoryEditField.Value = pwd; % Set current working directory as browse default
            fclose("all");
            delete("~temp\*");
            % load ftp credentials
            load_credentials(app,"~sp\secret");
            % load configuration
            load_configuration(app,"~sp\config");  
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                 
            % get numeric last dive
            app.diveSelected = convertCharsToStrings(app.DiveNListBox.Value);
            app.diveSelected = char(app.diveSelected);
            app.diveSelected = str2num(app.diveSelected(5:8));
            
            % get values from GUI
            app.totalDives = app.NoofdivesSpinner.Value;
            app.createcmd = false;
            
            % Start the script
            [ myresults, myfinalresults, app.object_results] = autoTrimv6_gui(str2double(app.sgid),app.diveSelected,app.totalDives,app.sg_full_path, app.createcmd, app.AverageSwitch.Value, app.SMCCSpinner.Value );
 
            app.UITable.Data = myresults;
            app.UITable.ColumnFormat(20) = {'bank'};
            app.UITable2.Data = myfinalresults;
            % update label
            sgfolder = app.GliderDropDown.Value;
            app.ResultsTextArea.Value = string(sgfolder); % set sgGGG label in RESULTS tab
            % clear cmdfile right whiteboard since a new run was made
            app.ImpliedcmdfileTextArea.Value = "Create new implied cmdfile for " + string(sgfolder) + newline + "Import cmdfile first.";
            app.impliedsgGGGEditField.Value = "sgGGG";
            app.impliedsgGGGEditField.Value = string(sgfolder); % set sgGGG label in the CMDFILE tab
            % disable copy button until create is pressed
            app.CopyButton.Enable = false;

%           app.myfinalresults - array:
%           1 -0 | 2-last_c_vbd | 3-round(imp_cvbd_ave,0) | 4-last_c_pitch 
%           5-round(imp_c_pitch_ave,0) | 6-last_pitch_gain | 7-round(imp_pitch_gain_ave,1)
%           8-last_c_roll_dive | 9-round(imp_croll_dive_ave,0) | 10-round(imp_croll_turn_dive_ave,0) 
%           11-round(croll_dive_ave,0) | 12-last_c_roll_climb | 13-round(imp_croll_climb_ave,0) 
%           14-round(imp_croll_turn_climb_ave,0) | 15-round(croll_climb_ave,0) 
%           16-last_sm_cc | 17-round(surf_min_ave,0) | 18-round(imp_sm_cc_ave,0)
%           19-last_max_buoy round(dog_ave,2)];
%           last_parameter is the last used by the glider from the cmdfile
              
        end

        % Value changed function: NoofdivesSpinner
        function NoofdivesSpinnerValueChanged(app, event)
            app.totalDives = app.NoofdivesSpinner.Value; % number of dives back to process
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % List directories in local folder
            % Get list with sgGGG and assign to dropdown
            sg_filter = strcat(app.WorkingdirectoryEditField.Value,"\sg*");
            sg_list = dir(sg_filter); % bring all the folders starting with 'sg'
            if isempty(sg_list)
                app.GliderDropDown.Items = "Empty";
                % pop up any window saying it's empty
            else
                sg_list = {sg_list.name}; % list of names cell array
                sg_list = convertCharsToStrings(sg_list); % convert cell array to string array
                % Clean array - may not be needed in the local dir
                i = 1;
                j = 1;
                clean_sg_list = [""]; % store only sgGGG folder names in this array
                while i <= length(sg_list)
                    if strlength(sg_list(i)) == 5 % keep only sgGGG 5 chars
                        temp = sg_list(i);
                        temp = char(temp);
                        if ~isnan(str2double(temp(3:5)))
                            clean_sg_list(j) = sg_list(i);
                            j =j+1;
                        end
                    end
                    i=i+1;
                end
                clean_sg_list = sort(clean_sg_list); % sort the sgGGG folder list
                app.GliderDropDown.Items = clean_sg_list; % display sgGGG folders in the dropdown
                GliderDropDownValueChanged(app, event); % execute to list local nc files
            end              
                                                     
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            bar = waitbar(0,"Connecting to server...");
            % Connect to server
            host = app.HostEditField.Value;
            user = app.UserEditField.Value;
            pass = app.PassEditField.Value;
            app.server = sftp(host,user,"Password",pass);
            % Show server info in text box
            text = "Host: " + app.server.Host + newline + "Port: " + app.server.Port + newline + "Dir: " + app.server.RemoteWorkingDirectory + newline + "System:" + app.server.ServerSystem ;
            app.ServerTextArea.Value = text;
            app.ConnectionLamp.Color = 'green';
            waitbar(0.5,bar,"Updating remote folder list");
            % Get list with sgGGG and assign to dropdown
            sg_list = dir(app.server,"/home2/sg*"); % bring all the folders starting with 'sg'
            sg_list = {sg_list.name}; % list of names cell array
            sg_list = convertCharsToStrings(sg_list); % convert cell array to string array- FULL LIST OF sgGGG
            app.full_remote_sgIDs = sg_list; % to use in other functions
            % if filter INACTIVE, then clean list, else use filter
            if app.ActiveCheckBox.Value == true
                % apply filter - if filter ACTIVE
                remote_sg_list = filterID(app,sg_list);
            else
                % Clean array only
                remote_sg_list = cleansgID(app,sg_list);
            end
            app.remoteFolderDropDown.Items = sort(remote_sg_list);

            % assign same sgGGG as in RESULTS
            if app.GliderDropDown.Value ~= "sgGGG"
                app.remoteFolderDropDown.Value = app.GliderDropDown.Value;
            end % else nothing
            waitbar(1,bar,"Remote folder list updated");
            pause(0.5);
            close(bar);
            app.UpdateButton.Enable = true;
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            close(app.server); % close connection with the server object
            app.ConnectionLamp.Color = 'red';      
            app.ServerTextArea.Value = "Disconnected";


        end

        % Button pushed function: remoteSelectButton
        function remoteSelectButtonPushed(app, event)
              bar = waitbar(0,"Updating remote NCDF list");
              % Get sgGGG folder from dropdown menu and list *.nc files
              sg_path = "/home2/" + string(app.remoteFolderDropDown.Value) + "/p*.nc";              
              nc_remote_list = dir(app.server,sg_path);
              nc_remote_list = {nc_remote_list.name};
              nc_remote_list = convertCharsToStrings(nc_remote_list);
              nc_remote_list = sort(nc_remote_list,"descend");

              app.RemoteNCDFListBox.Items = nc_remote_list;
              % Update Import/Export labels with sgGGG selected and enable
              app.ImportButton.Text = strcat("Import ",string(app.remoteFolderDropDown.Value));
              app.ImportButton.Enable = true;
              app.ExportButton.Text = strcat("Export ",string(app.remoteFolderDropDown.Value));
              app.ExportButton.Enable = true;
              % Clear cmdfile display
              app.RemotecmdfileTextArea.Value = strcat("Display cmdfile: " ,string(app.remoteFolderDropDown.Value));
              % Update KMZ button label
              app.OpenKmz.Text = "Open " + string(app.remoteFolderDropDown.Value) + " KMZ";              
              waitbar(1,bar,"Remote NCDF list updated");
              pause(0.5);
              close(bar);
              % clear remote sgGGG label since it changed
              app.remotesgGGGEditField.Value = "sgGGG";
              app.LastmodifiedLabel.Text = "";
             
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            bar = waitbar(0,"Starting download");

            nc_selection = app.RemoteNCDFListBox.Value; % cell array with selection of NC files
            
            % get GGG name folder from nc file name as string - REMOTE DIR
            nc_remotefolder = char(nc_selection(1)); % string to char array
            nc_remotefolder = string(nc_remotefolder(2:4)); % get GGG folder from nc filename as string
            nc_remotepath = "/home2/sg" + nc_remotefolder;
            cd(app.server,nc_remotepath); % set current dir to /home2/sgGGG/
            
            
            % create sgGGG dir if it doesn't exist - LOCAL DIR
            nc_localfolder = app.WorkingdirectoryEditField.Value + "\sg" + nc_remotefolder;
            if ~exist(nc_localfolder,"file")
                mkdir(nc_localfolder);
            end
            
            % download each file selected
            size = length(nc_selection);
            for i = 1:size
                nc_remotefile = string(nc_selection(i)); % cell element to string
                status = i/size;            
                waitbar(status,bar,nc_remotefile); % update progress bar
                mget(app.server,nc_remotefile, nc_localfolder);
            end
            waitbar(1,bar,"Download finished");
            pause(0.5);
            close(bar);

            % Message - Download complete
            app.LatestdownloadsTextArea.Value = nc_selection;

            GliderDropDownValueChanged(app, event); % execute to refresh NC list

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try
                app.local_download_dir = uigetdir();
                app.WorkingdirectoryEditField.Value = app.local_download_dir;
                % Update local sgGGG dirs and NC files
                RefreshButtonPushed(app, event); % update list of local sgGGG folders or Empty if none
                if (app.GliderDropDown.Value ~= "Empty")
                    GliderDropDownValueChanged(app, event); % update .nc files if not in wrong folder
                end
            catch e
                uialert(app.GliderTrimmerUIFigure,e.message,"Error");
            end
        end

        % Button pushed function: OpenKmz
        function OpenKmzPushed(app, event)
            % Download kmz from basestation     
            bar = waitbar(0,"Downloading remote KMZ...");
            sg = string(app.remoteFolderDropDown.Value); % sgGGG     
            cd(app.server,"/home2/" + sg); % /home2/sgGGG
            local_folder = app.WorkingdirectoryEditField.Value + "/" + sg; % ...path/sgGGG to download kmz
            % create dir if it does not exist yet
            if ~exist(local_folder,"file")
                mkdir(local_folder);
            end
            mget(app.server, sg + ".kmz", local_folder); 
            waitbar(0.5,bar,"Opening KMZ...");
            system("start " + local_folder + "/" + sg + ".kmz");
            waitbar(1,bar,"KMZ opened");
            pause(0.5);
            close(bar);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.remotesgGGGEditField.Value = string(app.remoteFolderDropDown.Value);
            app.CreateButton.Enable = true; % enable Create onle after importing cmdfile needed
            app.CheckLabel.Text = "";
            % Delete previous cmdfile if extists
            if exist("~temp/cmdfile_download","file")
                delete("~temp/cmdfile_download");
            end
            % Display remote cmdfile contents on textbox
            bar = waitbar(0,"Downloading remote cmdfile");
            sg = string(app.remoteFolderDropDown.Value); % sgGGG     
            cd(app.server,"/home2/" + sg); % /home2/sgGGG
            % create temporary folder to contain cmdfile locally
            if ~exist("~temp","dir")
                mkdir("~temp");
            end           
            mget(app.server, "cmdfile","~temp");                        
            file_path = "~temp/cmdfile";
            new_file_path = strcat(file_path,"_download");
            movefile(file_path,new_file_path); % rename cmdfile tcreatebuo cmdfile_download
            app.incoming_cmdfile = fileread(new_file_path);
            fclose('all'); % make sure it's closed
            app.RemotecmdfileTextArea.Value = app.incoming_cmdfile;  
            waitbar(0.5,bar,"Retreiving cmdfile date");
            % get cmdfile metadata
            app.cmdfile_meta = dir(app.server,"cmdfile");          
            app.LastmodifiedLabel.Text = strcat("Last modified:",string(app.cmdfile_meta.date), " UTC");
            
            waitbar(1,bar,"Remote cmdfile ready");    
            pause(0.5);
            close(bar);
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            zoom = app.ZoomSlider.Value;
            app.RemotecmdfileTextArea.FontSize = 14.0 + 1.1 * zoom;
            app.ImpliedcmdfileTextArea.FontSize = 14.0 + 1.1 * zoom;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % Parse local cmdfile and assign new suggested values
            if app.impliedsgGGGEditField.Value == app.remotesgGGGEditField.Value
                fclose("all"); % close all files in any opened
                app.CheckLabel.Text = "OK";
                app.CheckLabel.FontColor = "green";
                file_path = "~temp/cmdfile_download";
                output_text = createText(app, file_path);
                fclose("all"); % close all files in any opened
                app.ImpliedcmdfileTextArea.Value = output_text;
                app.CopyButton.Enable = true;
            else
                app.CheckLabel.Text = "WARNING: DIFFERENT GLIDERS";
                app.CheckLabel.FontColor = "red";
            end
            
        end

        % Callback function
        function GliderTrimmerUIFigureCloseRequest(app, event)
            waitbar(0,"Closing");
            fclose("all"); % close all files opened
            % Delete cmdfile
            if exist("~temp/cmdfile_download","file")
                delete("~temp/cmdfile_download");
            end
            % Disconnect server when app is closed
            close(app.server); 
            
            % EXIT APP
            delete(app);
        end

        % Value changed function: DiveNListBox
        function DiveNListBoxValueChanged(app, event)
            app.diveSelected = app.DiveNListBox.Value;
            
        end

        % Value changed function: GliderDropDown
        function GliderDropDownValueChanged(app, event)
            % When the Glider dropdown changes, list .nc files inside
            sgfolder = app.GliderDropDown.Value; % sgGGG selected
            app.sg_full_path = strcat(app.WorkingdirectoryEditField.Value,"\",sgfolder); % full path to sgGGG
            app.sgid = erase(sgfolder,'sg'); % get seaglider ID 'GGG'

            % List sorted NC files in dir            
            nc_filter = strcat(app.WorkingdirectoryEditField.Value, "\", sgfolder,"\p*.nc");
            nc_list = dir(nc_filter); % list nc files
            nc_list = {nc_list.name}; % list with pGGGDDDD.nc files as cell array
            nc_list = convertCharsToStrings(nc_list); % convert cell array to string array
            nc_list = sort(nc_list,"descend"); % sorted string array
            % Display NC files in list box for selection
            app.DiveNListBox.Items = nc_list;
            app.DiveNListBox.Value = nc_list(1);
      
            
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            % display clean suggested cmdfile on left panel
            app.RemotecmdfileTextArea.Value = app.new_text_clean;

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % ask again if user is sure
            confirmation = uiconfirm(app.GliderTrimmerUIFigure,strcat("Proceed to upload ",app.UploadcmdfilenameEditField.Value, " ?"), "Confirmation","Options",["Export","Cancel"]);
                                    
            % make sure you're in the same dir as shown in the sgGGG label
            if confirmation == "Export"
                bar = waitbar(0,strcat("Creating ",app.UploadcmdfilenameEditField.Value));
                % Create cmdfile to upload
                local_cmdfile_path = strcat("~temp/",app.UploadcmdfilenameEditField.Value);
                fout = fopen(local_cmdfile_path,'w');
                lines = length(app.RemotecmdfileTextArea.Value); % num of lines in text box
                for x = 1:lines
                    fprintf(fout,string(app.RemotecmdfileTextArea.Value{x})); % save what's in the remote cmdfile whiteboard
                    fprintf(fout,newline);
                end                        
                fclose(fout);
                waitbar(0.25, bar, strcat(app.UploadcmdfilenameEditField.Value, " ready to upload"));
                sg = string(app.remoteFolderDropDown.Value); % sgGGG    
                remote_path = "/home2/" + sg;
                waitbar(0.5, bar, strcat("Setting Current Dir: ", remote_path));
                cd(app.server, remote_path); % /home2/sgGGG
                waitbar(0.75, bar, strcat("Uploading ", app.UploadcmdfilenameEditField.Value));
                mput(app.server,local_cmdfile_path);
                pause(0.3);            
                waitbar(1.0, bar, strcat(app.UploadcmdfilenameEditField.Value, " uploaded!"));
                pause(0.3);
                close(bar);
                system(strcat("start notepad ~temp\",string(app.UploadcmdfilenameEditField.Value)));         
            end 
               
        end

        % Button pushed function: RemembermeButton
        function RemembermeButtonPushed(app, event)
            bar = waitbar(0,"Saving your credentials");
            pause(0.5);
            secret_path = "~sp";
            if ~exist(secret_path,"dir")
                mkdir(secret_path);
            end
            fclose("all");
            secret_fpath = strcat(secret_path,"\secret");
            fout = fopen(secret_fpath,"w"); % create file to store usr and pass            
            fprintf(fout,app.UserEditField.Value);
            fprintf(fout,newline);
            fprintf(fout,app.PassEditField.Value);
            fclose(fout);
            system("attrib +h " + strcat(pwd,"\",secret_fpath) ); % make it hidden
            fclose("all");
            if exist(secret_fpath,"file")
                waitbar(1,bar,"Credentials stored in working dir");
            end
            pause(0.5);
            close(bar);


        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            bar = waitbar(0,"Listing remote NCDF files...");
            % Set sftp folder to sgGGG to match Results
            app.remoteFolderDropDown.Value = app.GliderDropDown.Value;
            % Go to home2/sgGGG and list nc files
            remote_folder = strcat("/home2/",app.GliderDropDown.Value);
%             cd(app.server, remote_folder); % set current dir to sgGGG
            filter = strcat(remote_folder, "/p*.nc");
            nc_remote_list = dir(app.server, filter);
            nc_remote_list = {nc_remote_list.name};
            nc_remote_list = convertCharsToStrings(nc_remote_list);
            nc_remote_list = sort(nc_remote_list,"descend");            
            % get array with dives to download (Ndives back from last one)
            ndives = app.NoofdivesSpinner.Value; % No. of dives back
            list2download = [""]; % list of files to download
            for i = 1:ndives
                list2download(i) = nc_remote_list(i);
            end
            % list local .nc files
            waitbar(0.25,bar,"Listing local NCDF files...");
            local_dir = strcat( app.WorkingdirectoryEditField.Value,"\",app.GliderDropDown.Value ); % full path to local sgGGG
            nc_local_list = dir(local_dir);
            nc_local_list = {nc_local_list.name};
            nc_local_list = convertCharsToStrings(nc_local_list); % list of files already in local dir
            % download files if not in local dir
            waitbar(0.5,bar,"Starting downloads");
            cd(app.server, remote_folder); % set current dir to /home2/sgGGG
            len = length(list2download);
            for j = 1:len
                if ~ismember(list2download(j),nc_local_list) % if not in local dir
                    x = 0.5 + 0.5*j/len;
                    waitbar(x,bar,strcat("Downloading ",list2download(j)) );
%                     remote_file = strcat(remote_folder,"/",list2download(j));
                    mget(app.server, list2download(j), local_dir); % download file - already cd to sgGGG
                end
            end
            waitbar(1,bar,"Update done!");
            pause(0.5);
            close(bar);  
            GliderDropDownValueChanged(app, event); % execute to refresh NC list
            

        end

        % Button pushed function: SaveconfigurationButton
        function SaveconfigurationButtonPushed(app, event)
            bar = waitbar(0,"Saving your configuration");
            pause(0.5);
            secret_path = "~sp";
            if ~exist(secret_path,"dir")
                mkdir(secret_path);
            end
            fclose("all");
            config_fpath = strcat(secret_path,"\config");   
            % save data to config file, else pop up error message
            try
                fout = fopen(config_fpath,"w"); % create file to store config
                % save cvbd
                fprintf(fout,string(app.cvbd100.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.cvbd50.Value));
                fprintf(fout,newline);
                % save pitch
                fprintf(fout,string(app.pitch100.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.pitch50.Value));
                fprintf(fout,newline);
                % save roll
                fprintf(fout,string(app.croll100.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.croll50.Value));
                fprintf(fout,newline);
                % save roll type
                fprintf(fout,string(app.rollave.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.rollrate.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.roll.Value));
                fprintf(fout,newline);
                % save +smcc
                fprintf(fout,string(app.SMCCSpinner.Value));
                fprintf(fout,newline);
                % save +ndives
                fprintf(fout,string(app.NDIVESSpinner.Value));
                fprintf(fout,newline);
                % save +days
                fprintf(fout,string(app.DAYSSpinner.Value));
                fprintf(fout,newline);
                % save cmdfile name
                fprintf(fout,string(app.UploadcmdfilenameEditField.Value));
                fprintf(fout,newline);
                % save working dir
                wdir = strrep(string(app.WorkingdirectoryEditField.Value),"\","\\");
                fprintf(fout,wdir);
                fprintf(fout,newline);
                % save filter sgIDs
                filterIDS = list2string(app,app.FiltersgIDTextArea.Value); % string with sgGGG comma separated
                fprintf(fout,filterIDS);
                fprintf(fout,newline);

            catch e
                uialert(app.GliderTrimmerUIFigure,e.message,'Error');
                fclose("all");
            end
            fclose(fout);
            fclose("all");
            if exist(config_fpath,"file")
                waitbar(1,bar,"Configuration stored in working dir");
            end
            pause(0.5);
            close(bar);
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            bar = waitbar(0,"Updating filter");
            % if filter INACTIVE, then clean list, else use filter
            if app.ActiveCheckBox.Value == true
                % apply filter - if filter ACTIVE
                remote_sg_list = filterID(app,app.full_remote_sgIDs);
            else
                % Clean array only
                remote_sg_list = cleansgID(app,app.full_remote_sgIDs);
            end
            app.remoteFolderDropDown.Items = sort(remote_sg_list);
            waitbar(1,bar,"Filter applied");
            pause(0.5);
            close(bar);

        end

        % Button pushed function: ViewmanualButton
        function ViewmanualButtonPushed(app, event)
            try
                winopen("4900011-Piloting_Parameters_66-12.pdf");
            catch e
                uialert(app.GliderTrimmerUIFigure,e.message, "Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GliderTrimmerUIFigure = uifigure('Visible', 'off');
            app.GliderTrimmerUIFigure.Name = 'Glider Trimmer';
            app.GliderTrimmerUIFigure.Icon = 'compass.jpg';
            app.TabGroup = uitabgroup(app.GliderTrimmerUIFigure);
            app.RESULTSTab = uitab(app.TabGroup);
            app.RESULTSTab.Title = 'RESULTS';
            app.StartButton = uibutton(app.RESULTSTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResultsTextArea = uitextarea(app.RESULTSTab);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextArea.Value = {'sgGGG'};
            app.UITable = uitable(app.RESULTSTab);
            app.UITable.ColumnName = {'DiveN'; 'CVBD'; 'iCVBD'; 'CPITCH'; 'iCPITCH'; 'PGAIN'; 'iPGAIN'; 'CROLLDIVE'; 'iCROLLDIVE'; 'iCROLLTURNDIVE'; 'CROLLDIVEAVE'; 'CROLLCLIMB'; 'iCROLLCLIMB'; 'iCROLLTURNCLIMB'; 'CROLLCLIMBAVE'; 'SMCC'; 'SURFMIN'; 'iSMCC'; 'MAXBUOY'; 'DISTKM'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false false false false false false false false false false false false false false false];
            app.UITable2 = uitable(app.RESULTSTab);
            app.UITable2.ColumnName = {'DiveN'; 'CVBD'; 'iCVBD'; 'CPITCH'; 'iCPITCH'; 'PGAIN'; 'iPGAIN'; 'CROLLDIVE'; 'iCROLLDIVE'; 'iCROLLTURNDIVE'; 'CROLLDIVEAVE'; 'CROLLCLIMB'; 'iCROLLCLIMB'; 'iCROLLTURNCLIMB'; 'CROLLCLIMBAVE'; 'SMCC'; 'SURFMIN'; 'iSMCC'; 'MAXBUOY'; 'DISTKM'};
            app.UITable2.RowName = {};
            app.UITable2.Tooltip = {''};
            app.FINALAVERAGERESULTSLabel = uilabel(app.RESULTSTab);
            app.FINALAVERAGERESULTSLabel.Tooltip = {'i --> implied value'; 'Other values are shown as the parameters used by the glider during the last selected dive. Ex. CROLLCLIMB is the $C_ROLL_CLIMB value'; ''};
            app.FINALAVERAGERESULTSLabel.Text = 'FINAL AVERAGE RESULTS';
            app.INDIVIDUALRESULTSLabel = uilabel(app.RESULTSTab);
            app.INDIVIDUALRESULTSLabel.Text = 'INDIVIDUAL RESULTS';
            app.Label = uilabel(app.RESULTSTab);
            app.Label.Text = 'Select final results average type (weighed dive number or simple average):';
            app.Hyperlink = uihyperlink(app.RESULTSTab);
            app.Hyperlink.URL = 'https://www.aoml.noaa.gov/phod/goos/gliders/monitoring/Piloting_Monitoring.html';
            app.Hyperlink.Text = 'Piloting Monitoring page';
            app.NoofdivesSpinnerLabel = uilabel(app.RESULTSTab);
            app.NoofdivesSpinnerLabel.Text = 'No. of dives';
            app.NoofdivesSpinner = uispinner(app.RESULTSTab);
            app.NoofdivesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofdivesSpinnerValueChanged, true);
            app.NoofdivesSpinner.Value = 3;
            app.AverageSwitchLabel = uilabel(app.RESULTSTab);
            app.AverageSwitchLabel.Text = 'Average';
            app.AverageSwitch = uiswitch(app.RESULTSTab, 'slider');
            app.AverageSwitch.Items = {'Simple', 'Weighed'};
            app.AverageSwitch.Value = 'Simple';
            app.GliderDropDownLabel = uilabel(app.RESULTSTab);
            app.GliderDropDownLabel.Text = 'Glider';
            app.DiveNListBox = uilistbox(app.RESULTSTab);
            app.DiveNListBox.Items = {'sgGGG .nc files'};
            app.DiveNListBox.ValueChangedFcn = createCallbackFcn(app, @DiveNListBoxValueChanged, true);
            app.DiveNListBox.Tooltip = {'Select last local file to process'};
            app.DiveNListBox.Value = 'sgGGG .nc files';
            app.GliderDropDown = uidropdown(app.RESULTSTab);
            app.GliderDropDown.Items = {'sgGGG'};
            app.GliderDropDown.ValueChangedFcn = createCallbackFcn(app, @GliderDropDownValueChanged, true);
            app.GliderDropDown.Value = 'sgGGG';
            app.DiveNListBoxLabel = uilabel(app.RESULTSTab);
            app.DiveNListBoxLabel.Text = 'DiveN';
            app.RefreshButton = uibutton(app.RESULTSTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refresh local working directory'};
            app.RefreshButton.Text = 'Refresh';
            app.UpdateButton = uibutton(app.RESULTSTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.Image = uiimage(app.RESULTSTab);
            app.Image.ImageSource = 'aomloriginalperfect.jpg';
            app.SFTPTab = uitab(app.TabGroup);
            app.SFTPTab.Title = 'SFTP';
            app.UserEditFieldLabel = uilabel(app.SFTPTab);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.SFTPTab, 'text');
            app.RemoteNCDFListBoxLabel = uilabel(app.SFTPTab);
            app.RemoteNCDFListBoxLabel.Text = 'Remote NCDF';
            app.RemoteNCDFListBox = uilistbox(app.SFTPTab);
            app.RemoteNCDFListBox.Items = {'Remote NC list'};
            app.RemoteNCDFListBox.Multiselect = 'on';
            app.RemoteNCDFListBox.Tooltip = {'Select multiple files holding CTRL'};
            app.RemoteNCDFListBox.Value = {'Remote NC list'};
            app.ConnectButton = uibutton(app.SFTPTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.SFTPTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Tooltip = {'Remember to close connection at the end'};
            app.DisconnectButton.Text = 'Disconnect';
            app.HostEditFieldLabel = uilabel(app.SFTPTab);
            app.HostEditFieldLabel.Text = 'Host';
            app.HostEditField = uieditfield(app.SFTPTab, 'text');
            app.HostEditField.Editable = 'off';
            app.HostEditField.Value = '52.4.19.192';
            app.PassEditFieldLabel = uilabel(app.SFTPTab);
            app.PassEditFieldLabel.Text = 'Pass';
            app.PassEditField = uieditfield(app.SFTPTab, 'text');
            app.remoteFolderDropDown = uidropdown(app.SFTPTab);
            app.remoteFolderDropDown.Items = {'sgGGG'};
            app.remoteFolderDropDown.Tooltip = {'Select remote folder'};
            app.remoteFolderDropDown.Value = 'sgGGG';
            app.remoteSelectButton = uibutton(app.SFTPTab, 'push');
            app.remoteSelectButton.ButtonPushedFcn = createCallbackFcn(app, @remoteSelectButtonPushed, true);
            app.remoteSelectButton.Text = 'Select';
            app.DownloadButton = uibutton(app.SFTPTab, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Tooltip = {'Download selection. Files are stored in the corresponding sgGGG folder inside the Localdirectory'};
            app.DownloadButton.Text = 'Download';
            app.ServerTextAreaLabel = uilabel(app.SFTPTab);
            app.ServerTextAreaLabel.Text = 'Server ';
            app.ServerTextArea = uitextarea(app.SFTPTab);
            app.ServerTextArea.Editable = 'off';
            app.ServerTextArea.Enable = 'off';
            app.ConnectionLamp = uilamp(app.SFTPTab);
            app.LatestdownloadsLabel = uilabel(app.SFTPTab);
            app.LatestdownloadsLabel.Enable = 'off';
            app.LatestdownloadsLabel.Text = 'Latest downloads';
            app.LatestdownloadsTextArea = uitextarea(app.SFTPTab);
            app.LatestdownloadsTextArea.Editable = 'off';
            app.LatestdownloadsTextArea.Enable = 'off';
            app.OpenKmz = uibutton(app.SFTPTab, 'push');
            app.OpenKmz.ButtonPushedFcn = createCallbackFcn(app, @OpenKmzPushed, true);
            app.OpenKmz.Tag = 'openKmz';
            app.OpenKmz.Tooltip = {'Select sgGGG remote folder first'};
            app.OpenKmz.Text = 'Open sgGGG KMZ';
            app.RemembermeButton = uibutton(app.SFTPTab, 'push');
            app.RemembermeButton.ButtonPushedFcn = createCallbackFcn(app, @RemembermeButtonPushed, true);
            app.RemembermeButton.Text = 'Remember me';
            app.CMDFILETab = uitab(app.TabGroup);
            app.CMDFILETab.Title = 'CMDFILE';
            app.RemotecmdfileLabel = uilabel(app.CMDFILETab);
            app.RemotecmdfileLabel.Text = {'Remote '; 'cmdfile:'};
            app.RemotecmdfileTextArea = uitextarea(app.CMDFILETab);
            app.RemotecmdfileTextArea.Tooltip = {'Display cmdfile from basestation'};
            app.RemotecmdfileTextArea.Value = {'Select sgGGG from SFTP tab first.'; ''; 'Press Import to display cmdfile online.'; 'Press Export to upload this cmdfile.'; ''; ''; ''};
            app.ImportButton = uibutton(app.CMDFILETab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Tooltip = {'Display current remote cmdfile. Remote folder must be selected first'};
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.CMDFILETab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Tooltip = {'Uploads cmdfile to the selected sgGGG remote folder'};
            app.ExportButton.Text = 'Export';
            app.remotesgGGGEditField = uieditfield(app.CMDFILETab, 'text');
            app.remotesgGGGEditField.Editable = 'off';
            app.remotesgGGGEditField.Value = 'sgGGG';
            app.ZoomSliderLabel = uilabel(app.CMDFILETab);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.CMDFILETab);
            app.ZoomSlider.Limits = [-10 10];
            app.ZoomSlider.MajorTicks = [-10 -5 0 5 10];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ImpliedcmdfileLabel = uilabel(app.CMDFILETab);
            app.ImpliedcmdfileLabel.Text = {'Implied '; 'cmdfile:'};
            app.ImpliedcmdfileTextArea = uitextarea(app.CMDFILETab);
            app.ImpliedcmdfileTextArea.Editable = 'off';
            app.ImpliedcmdfileTextArea.Tooltip = {'Values in parentheses are the difference with the parameters set in the last dive (processed), NOT THE CURRENT REMOTE CMDFILE.'};
            app.ImpliedcmdfileTextArea.Value = {'Process RESULTS tab and Import cmdfile first.'; ''; 'Press Create to display suggested cmdfile based on the remote cmdfile.'; ''; 'Values in parentheses are the difference between the parameters in the last dive (processed), NOT THE CURRENT REMOTE CMDFILE.'; ''; 'This won''t run unless both remote and implied sgGGG are the same '};
            app.impliedsgGGGEditField = uieditfield(app.CMDFILETab, 'text');
            app.impliedsgGGGEditField.Editable = 'off';
            app.impliedsgGGGEditField.Value = 'sgGGG';
            app.remotecmdfileLabel = uilabel(app.CMDFILETab);
            app.remotecmdfileLabel.Text = {'remote'; 'cmdfile'};
            app.impliedcmdfileLabel = uilabel(app.CMDFILETab);
            app.impliedcmdfileLabel.Text = {'implied '; 'cmdfile'};
            app.CreateButton = uibutton(app.CMDFILETab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.CheckLabel = uilabel(app.CMDFILETab);
            app.CheckLabel.Text = 'Check';
            app.LastmodifiedLabel = uilabel(app.CMDFILETab);
            app.LastmodifiedLabel.Tooltip = {'Date-time of last modification of remote cmdfile'};
            app.LastmodifiedLabel.Text = 'Last modified';
            app.CopyButton = uibutton(app.CMDFILETab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Enable = 'off';
            app.CopyButton.Tooltip = {'Copy suggested cmdfile to Remote cmdfile whiteboard'};
            app.CopyButton.Text = '<<<  Copy  <<<';
            app.CONFIGTab = uitab(app.TabGroup);
            app.CONFIGTab.Title = 'CONFIG';
            app.SMCCSpinnerLabel = uilabel(app.CONFIGTab);
            app.SMCCSpinnerLabel.Text = '+SMCC';
            app.SMCCSpinner = uispinner(app.CONFIGTab);
            app.SMCCSpinner.Tooltip = {'Implied SM_CC will be surfmin + this value'};
            app.SMCCSpinner.Value = 25;
            app.LocaldirectorydirectorytocontainsgGGGfoldersEditFieldLabel = uilabel(app.CONFIGTab);
            app.LocaldirectorydirectorytocontainsgGGGfoldersEditFieldLabel.Text = 'Working directory';
            app.WorkingdirectoryEditField = uieditfield(app.CONFIGTab, 'text');
            app.WorkingdirectoryEditField.Tooltip = {'Select parent folder containing or to contain the sgGGG folders.'; 'If no sgGGG folder, a new one will be created when downloading files. '; ''; 'Default location is where the App is  executed'};
            app.BrowseButton = uibutton(app.CONFIGTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NDIVESSpinnerLabel = uilabel(app.CONFIGTab);
            app.NDIVESSpinnerLabel.Text = '+NDIVES';
            app.NDIVESSpinner = uispinner(app.CONFIGTab);
            app.NDIVESSpinner.Value = 20;
            app.C_PITCHPGAINButtonGroup = uibuttongroup(app.CONFIGTab);
            app.C_PITCHPGAINButtonGroup.Title = 'C_PITCH / PGAIN';
            app.pitch100 = uiradiobutton(app.C_PITCHPGAINButtonGroup);
            app.pitch100.Text = '100%';
            app.pitch100.Value = true;
            app.pitch50 = uiradiobutton(app.C_PITCHPGAINButtonGroup);
            app.pitch50.Text = '50%';
            app.C_ROLLButtonGroup = uibuttongroup(app.CONFIGTab);
            app.C_ROLLButtonGroup.Title = 'C_ROLL';
            app.croll100 = uiradiobutton(app.C_ROLLButtonGroup);
            app.croll100.Text = '100%';
            app.croll50 = uiradiobutton(app.C_ROLLButtonGroup);
            app.croll50.Text = '50%';
            app.croll50.Value = true;
            app.C_ROLLTYPEButtonGroup = uibuttongroup(app.CONFIGTab);
            app.C_ROLLTYPEButtonGroup.Title = 'C_ROLLTYPE';
            app.roll = uiradiobutton(app.C_ROLLTYPEButtonGroup);
            app.roll.Text = 'ROLL';
            app.rollrate = uiradiobutton(app.C_ROLLTYPEButtonGroup);
            app.rollrate.Text = 'RATE';
            app.rollrate.Value = true;
            app.rollave = uiradiobutton(app.C_ROLLTYPEButtonGroup);
            app.rollave.Text = 'AVERAGE';
            app.C_VBDButtonGroup = uibuttongroup(app.CONFIGTab);
            app.C_VBDButtonGroup.Title = 'C_VBD';
            app.cvbd100 = uiradiobutton(app.C_VBDButtonGroup);
            app.cvbd100.Text = '100%';
            app.cvbd50 = uiradiobutton(app.C_VBDButtonGroup);
            app.cvbd50.Text = '50%';
            app.cvbd50.Value = true;
            app.DAYSSpinnerLabel = uilabel(app.CONFIGTab);
            app.DAYSSpinnerLabel.Text = '+DAYS';
            app.DAYSSpinner = uispinner(app.CONFIGTab);
            app.DAYSSpinner.Value = 3;
            app.UploadcmdfilenameLabel = uilabel(app.CONFIGTab);
            app.UploadcmdfilenameLabel.Text = 'Upload cmdfile name:';
            app.UploadcmdfilenameEditField = uieditfield(app.CONFIGTab, 'text');
            app.UploadcmdfilenameEditField.Tooltip = {'cmdfile is uploaded with this name'};
            app.UploadcmdfilenameEditField.Value = 'cmdfile_upload';
            app.DepthDropDownLabel = uilabel(app.CONFIGTab);
            app.DepthDropDownLabel.Enable = 'off';
            app.DepthDropDownLabel.Text = 'Depth';
            app.DepthDropDown = uidropdown(app.CONFIGTab);
            app.DepthDropDown.Items = {'cmdfile', '960', '900', '800', '700', '600', '550', '500', '450', '400', '350', '300', '250', '200', '150', '100', '45'};
            app.DepthDropDown.Enable = 'off';
            app.DepthDropDown.Tooltip = {'Create implied cmdfile after changing depth.'; 'If cmdfile is selected (default), depth is taken as is from imported cmdfile'};
            app.DepthDropDown.Value = 'cmdfile';
            app.SaveconfigurationButton = uibutton(app.CONFIGTab, 'push');
            app.SaveconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigurationButtonPushed, true);
            app.SaveconfigurationButton.Text = 'Save configuration';
            app.FiltersgIDLabel = uilabel(app.CONFIGTab);
            app.FiltersgIDLabel.Text = 'Filter sgID';
            app.FiltersgIDTextArea = uitextarea(app.CONFIGTab);
            app.FiltersgIDTextArea.Tooltip = {'Filter remote sgGGG folders to show only the ones listed here'};
            app.FiltersgIDTextArea.Value = {'sg610'; 'sg649'; 'sg665'; 'sg667'; 'sg670'; 'sg678'};
            app.ApplyfilterButton = uibutton(app.CONFIGTab, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.ActiveCheckBox = uicheckbox(app.CONFIGTab);
            app.ActiveCheckBox.Text = 'Active';
            app.ActiveCheckBox.Value = true;
            app.DefaultconfigurationButton = uibutton(app.CONFIGTab, 'state');
            app.DefaultconfigurationButton.Enable = 'off';
            app.DefaultconfigurationButton.Text = 'Default configuration';
            app.ViewmanualButton = uibutton(app.CONFIGTab, 'push');
            app.ViewmanualButton.ButtonPushedFcn = createCallbackFcn(app, @ViewmanualButtonPushed, true);
            app.ViewmanualButton.Text = 'View manual';
            app.NOAAAOMLGliderTrimmerv6CLabel = uilabel(app.GliderTrimmerUIFigure);
            app.NOAAAOMLGliderTrimmerv6CLabel.Text = '10/31/2022 NOAA/AOML GliderTrimmer-v6C';
            % Show the figure after all components are created
            app.GliderTrimmerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoTrimApp_v6C

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GliderTrimmerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GliderTrimmerUIFigure)
        end
    end
end